classdef ARLO_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARLOControlUIFigure            matlab.ui.Figure
        BackwardButton                 matlab.ui.control.Button
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        ForwardButton                  matlab.ui.control.Button
        angleEditField_2Label          matlab.ui.control.Label
        leftField                      matlab.ui.control.NumericEditField
        cmEditFieldLabel               matlab.ui.control.Label
        forwardField                   matlab.ui.control.NumericEditField
        angleEditFieldLabel            matlab.ui.control.Label
        rightField                     matlab.ui.control.NumericEditField
        cmEditField_3Label             matlab.ui.control.Label
        backwardField                  matlab.ui.control.NumericEditField
        SelectSerialPortDropDownLabel  matlab.ui.control.Label
        SelectSerialPortDropDown       matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        ARLOCONTROLLabel               matlab.ui.control.Label
        OLabel                         matlab.ui.control.Label
        SpeedSliderLabel               matlab.ui.control.Label
        SpeedSlider                    matlab.ui.control.Slider
    end


    properties (Access = private)
        port; % serial port variable
    end

    methods (Access = private)
        % function that maps a distance in centimeters to the number of encoder pulses
        function results = dis2enc(~, dis)
            results = 10*dis; % still need to determine the actual ratio
        end
        
        % function that maps a turn angle in degrees to the number of encoder pulses
        function results = ang2enc(~, ang)
            results = 10*ang; % still need to determine the actual ratio
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % populate drop down with available serial ports
            app.SelectSerialPortDropDown.Items = seriallist;
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if (class(app.port) == 'serial') % if the serial object has been created
                if (app.port.Status == 'open')  % check if port is open
                    enc = dis2enc(app, app.forwardField.Value); % map distance in forward field to number of encoder pulses
                    enc = int2str(enc); % change to string
                    speed = int2str(app.SpeedSlider.Value); % grab speed value from speed slider
                    
                    % create command and send across serial port (commands are defined by ARLO docs)
                    cmd = ['MOVE', ' ', enc, ' ', enc, ' ', speed];
                    fprintf('Sending %s\n', cmd);
                    fprintf(app.port, cmd);
                end
            end
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            if (class(app.port) == 'serial') % if the serial object has been created
                if (app.port.Status == 'open')  % and is open
                    enc = -dis2enc(app, app.backwardField.Value); % map distance in backward field to number of encoder pulses
                    enc = int2str(enc); % change int to string for sending over serial
                    speed = int2str(app.SpeedSlider.Value); % grab speed value from speed slider
                    
                    % create command and send across serial port (commands are defined by ARLO docs)
                    cmd = ['MOVE', ' ', enc, ' ', enc, ' ', speed];
                    fprintf('Sending %s\n', cmd);
                    fprintf(app.port, cmd);
                end
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if (class(app.port) == 'serial') % if the serial object has been created
                if (app.port.Status == 'open')  % and is open
                    % map angle in left field to number of encoder pulses
                    enc = ang2enc(app, app.leftField.Value);
                    leftEnc = int2str(-enc); % turning requires different directions for each wheel
                    rightEnc = int2str(enc); % change ints to strings for sending over serial
                    speed = int2str(app.SpeedSlider.Value); % grab speed value from speed slider
                    
                    % create command and send across serial port (commands are defined by ARLO docs)
                    cmd = ['MOVE', ' ', leftEnc, ' ', rightEnc, ' ', speed];
                    fprintf('Sending %s\n', cmd);
                    fprintf(app.port, cmd);
                end
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if (class(app.port) == 'serial') % if the serial object has been created
                if (app.port.Status == 'open')  % and is open
                    % map angle in right field to number of encoder pulses
                    enc = ang2enc(app, app.rightField.Value);
                    leftEnc = int2str(enc); % turning requires different directions for each wheel
                    rightEnc = int2str(-enc); % change ints to strings for sending over serial
                    speed = int2str(app.SpeedSlider.Value); % grab speed value from speed slider
                    
                    % create command and send across serial port (commands are defined by ARLO docs)
                    cmd = ['MOVE', ' ', leftEnc, ' ', rightEnc, ' ', speed];
                    fprintf('Sending %s\n', cmd);
                    fprintf(app.port, cmd);
                end
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (app.ConnectButton.Text == 'Connect')
                % attempt connecting to serial port selected in drop down menu
                instrreset; % disconnect all port connections first
                app.port = serial(app.SelectSerialPortDropDown.Value);
                fopen(app.port);
                if (app.port.Status == 'open')
                    % if the port is now open change value of connect button to reflect success
                    app.ConnectButton.Text = 'Disconnect';
                    app.port.Terminator = 'CR'; % change terminator on serial to \r (ARLO expects this)
                end
            else % close port if disconnect button is pressed
                fclose(app.port);
                if (app.port.Status == 'closed')
                    % if the port is now closed change value of disconnect button to reflect success
                    app.ConnectButton.Text = 'Connect';
                end
            end
        end

        % Close request function: ARLOControlUIFigure
        function ARLOControlUIFigureCloseRequest(app, event)
            if (class(app.port) == 'serial') % if the serial object has been created
                if (app.port.Status == 'open')  % check if port is open
                    fclose(app.port);   % if open, close port before cleaning up
                    delete(app.port);
                else
                    delete(app.port);   % if already closed then just clean up
                end
            end
            
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARLOControlUIFigure = uifigure;
            app.ARLOControlUIFigure.Name = 'ARLO Control';
            app.ARLOControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @ARLOControlUIFigureCloseRequest, true);
            app.BackwardButton = uibutton(app.ARLOControlUIFigure, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = 'Backward';
            app.LeftButton = uibutton(app.ARLOControlUIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.ARLOControlUIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ForwardButton = uibutton(app.ARLOControlUIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.angleEditField_2Label = uilabel(app.ARLOControlUIFigure);
            app.angleEditField_2Label.Text = 'angle';
            app.leftField = uieditfield(app.ARLOControlUIFigure, 'numeric');
            app.leftField.Limits = [0 360];
            app.cmEditFieldLabel = uilabel(app.ARLOControlUIFigure);
            app.cmEditFieldLabel.Text = 'cm';
            app.forwardField = uieditfield(app.ARLOControlUIFigure, 'numeric');
            app.forwardField.Limits = [0 Inf];
            app.angleEditFieldLabel = uilabel(app.ARLOControlUIFigure);
            app.angleEditFieldLabel.Text = 'angle';
            app.rightField = uieditfield(app.ARLOControlUIFigure, 'numeric');
            app.rightField.Limits = [0 360];
            app.cmEditField_3Label = uilabel(app.ARLOControlUIFigure);
            app.cmEditField_3Label.Text = 'cm';
            app.backwardField = uieditfield(app.ARLOControlUIFigure, 'numeric');
            app.backwardField.Limits = [0 Inf];
            app.SelectSerialPortDropDownLabel = uilabel(app.ARLOControlUIFigure);
            app.SelectSerialPortDropDownLabel.Text = 'Select Serial Port';
            app.SelectSerialPortDropDown = uidropdown(app.ARLOControlUIFigure);
            app.ConnectButton = uibutton(app.ARLOControlUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ARLOCONTROLLabel = uilabel(app.ARLOControlUIFigure);
            app.ARLOCONTROLLabel.Text = 'ARLO CONTROL';
            app.OLabel = uilabel(app.ARLOControlUIFigure);
            app.OLabel.Text = 'O';
            app.SpeedSliderLabel = uilabel(app.ARLOControlUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ARLOControlUIFigure);
            app.SpeedSlider.Limits = [30 200];
            app.SpeedSlider.MinorTicks = [];
            app.SpeedSlider.Value = 80;
        end
    end

    methods (Access = public)

        % Construct app
        function app = ARLO_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARLOControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARLOControlUIFigure)
        end
    end
end